classdef ISETBioCSFGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ISETBioCSFGeneratorDevelopedbyNPCottarisISETBioTeam2021UIFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        fixationaleyemovementsTab     matlab.ui.container.Tab
        opticsTab                     matlab.ui.container.Tab
        conemosaicTab                 matlab.ui.container.Tab
        mRGCmosaicTab                 matlab.ui.container.Tab
        pRGCmosaicTab                 matlab.ui.container.Tab
        TabGroup2                     matlab.ui.container.TabGroup
        visualFieldTab                matlab.ui.container.Tab
        visualFieldEyeSwitch          matlab.ui.control.Switch
        radialeccentricitydegsLabel   matlab.ui.control.Label
        visualFieldRadialEccentricitySlider  matlab.ui.control.Slider
        polareccentricitydegsLabel    matlab.ui.control.Label
        visualFieldPolarEccentricityKnob  matlab.ui.control.Knob
        fieldofviewdegsLabel          matlab.ui.control.Label
        visualFieldFieldOfViewSlider  matlab.ui.control.Slider
        magnificationfactorumdegEditFieldLabel  matlab.ui.control.Label
        visualFieldMagnificationFactorEditField  matlab.ui.control.NumericEditField
        visualFieldView               matlab.ui.control.UIAxes
        stimulusTab                   matlab.ui.container.Tab
        performanceassessmentTab      matlab.ui.container.Tab
        Panel                         matlab.ui.container.Panel
        leftStatusField               matlab.ui.control.EditField
        Panel_2                       matlab.ui.container.Panel
        rightStatusField              matlab.ui.control.EditField
    end

    
    properties (Access = public)
        visualFieldParams % struct with all visual field params
        coneMosaicParams  % struct with all cone mosaic params
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            CSFGeneratorApp.initializeSimulationParams(app);
            CSFGeneratorApp.initializeGUIComponents(app);
            CSFGeneratorApp.updateAllViews(app);
        end

        % Value changed function: visualFieldEyeSwitch
        function visualFieldEyeSwitchValueChanged(app, event)
            value = app.visualFieldEyeSwitch.Value;
            CSFGeneratorApp.decodeEyeSwitch(value);
        end

        % Value changed function: visualFieldPolarEccentricityKnob
        function visualFieldPolarEccentricityKnobValueChanged(app, event)
            value = app.visualFieldPolarEccentricityKnob.Value;
            CSFGeneratorApp.decodePolarEccentricitySlider(app, 'sliderToValue', value);
        end

        % Value changing function: visualFieldPolarEccentricityKnob
        function visualFieldPolarEccentricityKnobValueChanging(app, event)
            changingValue = event.Value;
            CSFGeneratorApp.decodePolarEccentricitySlider(app, 'sliderToValue', changingValue);
        end

        % Value changed function: 
        % visualFieldRadialEccentricitySlider
        function visualFieldRadialEccentricitySliderValueChanged(app, event)
            value = app.visualFieldRadialEccentricitySlider.Value;
            CSFGeneratorApp.decodeRadialEccentricitySlider(app, 'sliderToValue', value);
        end

        % Value changing function: 
        % visualFieldRadialEccentricitySlider
        function visualFieldRadialEccentricitySliderValueChanging(app, event)
            changingValue = event.Value;
            CSFGeneratorApp.decodeRadialEccentricitySlider(app, 'sliderToValue', changingValue);
        end

        % Value changed function: visualFieldFieldOfViewSlider
        function visualFieldFieldOfViewSliderValueChanged(app, event)
            value = app.visualFieldFieldOfViewSlider.Value;
            CSFGeneratorApp.decodeFieldOfViewSlider(app, 'sliderToValue', value);
        end

        % Value changing function: visualFieldFieldOfViewSlider
        function visualFieldFieldOfViewSliderValueChanging(app, event)
            changingValue = event.Value;
            CSFGeneratorApp.decodeFieldOfViewSlider(app, 'sliderToValue', changingValue);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ISETBioCSFGeneratorDevelopedbyNPCottarisISETBioTeam2021UIFigure = uifigure('Visible', 'off');
            app.ISETBioCSFGeneratorDevelopedbyNPCottarisISETBioTeam2021UIFigure.Name = 'ISETBio CSF Generator, Developed by N.P. Cottaris, ISETBio Team, 2021';
            app.GridLayout = uigridlayout(app.ISETBioCSFGeneratorDevelopedbyNPCottarisISETBioTeam2021UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.fixationaleyemovementsTab = uitab(app.TabGroup);
            app.fixationaleyemovementsTab.Title = 'fixational eye movements';
            app.opticsTab = uitab(app.TabGroup);
            app.opticsTab.Title = 'optics';
            app.conemosaicTab = uitab(app.TabGroup);
            app.conemosaicTab.Title = 'cone mosaic ';
            app.mRGCmosaicTab = uitab(app.TabGroup);
            app.mRGCmosaicTab.Title = 'mRGC mosaic';
            app.pRGCmosaicTab = uitab(app.TabGroup);
            app.pRGCmosaicTab.Title = 'pRGC mosaic';
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.visualFieldTab = uitab(app.TabGroup2);
            app.visualFieldTab.Title = 'visual field';
            app.visualFieldEyeSwitch = uiswitch(app.visualFieldTab, 'slider');
            app.visualFieldEyeSwitch.Items = {'left eye', 'right eye'};
            app.visualFieldEyeSwitch.ValueChangedFcn = createCallbackFcn(app, @visualFieldEyeSwitchValueChanged, true);
            app.visualFieldEyeSwitch.Value = 'right eye';
            app.radialeccentricitydegsLabel = uilabel(app.visualFieldTab);
            app.radialeccentricitydegsLabel.Text = {'radial eccentricity (degs) '; ''};
            app.visualFieldRadialEccentricitySlider = uislider(app.visualFieldTab);
            app.visualFieldRadialEccentricitySlider.ValueChangedFcn = createCallbackFcn(app, @visualFieldRadialEccentricitySliderValueChanged, true);
            app.visualFieldRadialEccentricitySlider.ValueChangingFcn = createCallbackFcn(app, @visualFieldRadialEccentricitySliderValueChanging, true);
            app.polareccentricitydegsLabel = uilabel(app.visualFieldTab);
            app.polareccentricitydegsLabel.Text = 'polar eccentricity (degs) ';
            app.visualFieldPolarEccentricityKnob = uiknob(app.visualFieldTab, 'continuous');
            app.visualFieldPolarEccentricityKnob.Limits = [0 360];
            app.visualFieldPolarEccentricityKnob.ValueChangedFcn = createCallbackFcn(app, @visualFieldPolarEccentricityKnobValueChanged, true);
            app.visualFieldPolarEccentricityKnob.ValueChangingFcn = createCallbackFcn(app, @visualFieldPolarEccentricityKnobValueChanging, true);
            app.fieldofviewdegsLabel = uilabel(app.visualFieldTab);
            app.fieldofviewdegsLabel.Text = 'field of view (degs) ';
            app.visualFieldFieldOfViewSlider = uislider(app.visualFieldTab);
            app.visualFieldFieldOfViewSlider.ValueChangedFcn = createCallbackFcn(app, @visualFieldFieldOfViewSliderValueChanged, true);
            app.visualFieldFieldOfViewSlider.ValueChangingFcn = createCallbackFcn(app, @visualFieldFieldOfViewSliderValueChanging, true);
            app.magnificationfactorumdegEditFieldLabel = uilabel(app.visualFieldTab);
            app.magnificationfactorumdegEditFieldLabel.Text = 'magnification factor (um/deg)';
            app.visualFieldMagnificationFactorEditField = uieditfield(app.visualFieldTab, 'numeric');
            app.visualFieldView = uiaxes(app.visualFieldTab);
            xlabel(app.visualFieldView, 'X')
            zlabel(app.visualFieldView, 'Z')
            app.stimulusTab = uitab(app.TabGroup2);
            app.stimulusTab.Title = 'stimulus';
            app.performanceassessmentTab = uitab(app.TabGroup2);
            app.performanceassessmentTab.Title = 'performance assessment';
            app.Panel = uipanel(app.GridLayout);
            app.leftStatusField = uieditfield(app.Panel, 'text');
            app.leftStatusField.Editable = 'off';
            app.leftStatusField.Value = 'left status';
            app.Panel_2 = uipanel(app.GridLayout);
            app.rightStatusField = uieditfield(app.Panel_2, 'text');
            app.rightStatusField.Editable = 'off';
            app.rightStatusField.Value = 'right status';
            % Show the figure after all components are created
            app.ISETBioCSFGeneratorDevelopedbyNPCottarisISETBioTeam2021UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ISETBioCSFGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ISETBioCSFGeneratorDevelopedbyNPCottarisISETBioTeam2021UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ISETBioCSFGeneratorDevelopedbyNPCottarisISETBioTeam2021UIFigure)
        end
    end
end